classdef UI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        signal_generate                matlab.ui.control.Button
        AntennaconfigurationPanel      matlab.ui.container.Panel
        totaltimeEditFieldLabel        matlab.ui.control.Label
        total_time                     matlab.ui.control.NumericEditField
        numberofchirpsEditFieldLabel   matlab.ui.control.Label
        number_of_chirps               matlab.ui.control.NumericEditField
        bandwidthEditFieldLabel        matlab.ui.control.Label
        band_width                     matlab.ui.control.NumericEditField
        numberofsamplesEditFieldLabel  matlab.ui.control.Label
        number_of_samples              matlab.ui.control.NumericEditField
        antennapositionsLabel          matlab.ui.control.Label
        Antenna_positions              matlab.ui.control.TextArea
        carrierfrequenceTextAreaLabel  matlab.ui.control.Label
        carrier_frequence              matlab.ui.control.TextArea
        ObjectconfigrationPanel        matlab.ui.container.Panel
        rangeEditFieldLabel            matlab.ui.control.Label
        range                          matlab.ui.control.NumericEditField
        speedEditFieldLabel            matlab.ui.control.Label
        speed                          matlab.ui.control.NumericEditField
        DOAEditFieldLabel              matlab.ui.control.Label
        DOA                            matlab.ui.control.EditField
        varianceofnoiseLabel           matlab.ui.control.Label
        variance                       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        radarParameter;
        objectParameter;
        generated_signal;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: signal_generate
        function signal_generateButtonPushed(app, event)
            % set radar parameters
            f0 = str2num(app.carrier_frequence.Value{1});
            B = app.band_width.Value;
            t_full = app.total_time.Value;
            N_chirp = app.number_of_chirps.Value;
            N_sample = app.number_of_samples.Value;
            P = str2num(app.Antenna_positions.Value{1});
            app.radarParameter = defineRadar(f0, B, t_full, N_chirp, N_sample, P);
            
            %set object parameters
            r0 = app.range.Value;
            vr = app.speed.Value;
            u = str2num(app.DOA.Value);
            sigma = app.variance.Value;
            app.objectParameter = defineObject(r0, vr, u, sigma);
            
            %generate signal
            app.generated_signal = signalGenerator_SO(app.radarParameter, app.objectParameter);
            
            %plot
            stem(app.UIAxes, fft(app.generated_signal(:, 1,1)));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.signal_generate = uibutton(app.UIFigure, 'push');
            app.signal_generate.ButtonPushedFcn = createCallbackFcn(app, @signal_generateButtonPushed, true);
            app.signal_generate.Text = {'genrate'; ''};
            app.AntennaconfigurationPanel = uipanel(app.UIFigure);
            app.AntennaconfigurationPanel.Title = 'Antenna configuration';
            app.totaltimeEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.totaltimeEditFieldLabel.Text = 'total time';
            app.total_time = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.total_time.ValueDisplayFormat = '%.2e';
            app.total_time.Value = 0.008;
            app.numberofchirpsEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.numberofchirpsEditFieldLabel.Text = 'number of chirps';
            app.number_of_chirps = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.number_of_chirps.ValueDisplayFormat = '%.0f';
            app.number_of_chirps.Value = 1000;
            app.bandwidthEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.bandwidthEditFieldLabel.Text = 'bandwidth';
            app.band_width = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.band_width.Value = 1000000000;
            app.numberofsamplesEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.numberofsamplesEditFieldLabel.Text = 'number of samples';
            app.number_of_samples = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.number_of_samples.ValueDisplayFormat = '%.0f';
            app.number_of_samples.Value = 36;
            app.antennapositionsLabel = uilabel(app.AntennaconfigurationPanel);
            app.antennapositionsLabel.Text = {'antenna '; 'positions'};
            app.Antenna_positions = uitextarea(app.AntennaconfigurationPanel);
            app.Antenna_positions.Value = {'[0, 0, 0; 1/2, 0, 0; 0, 1/2, 0; 1/2, 1/2, 0]'};
            app.carrierfrequenceTextAreaLabel = uilabel(app.AntennaconfigurationPanel);
            app.carrierfrequenceTextAreaLabel.Text = 'carrier frequence';
            app.carrier_frequence = uitextarea(app.AntennaconfigurationPanel);
            app.carrier_frequence.Value = {'70e9'};
            app.ObjectconfigrationPanel = uipanel(app.UIFigure);
            app.ObjectconfigrationPanel.Title = 'Object configration';
            app.rangeEditFieldLabel = uilabel(app.ObjectconfigrationPanel);
            app.rangeEditFieldLabel.Text = 'range';
            app.range = uieditfield(app.ObjectconfigrationPanel, 'numeric');
            app.range.Value = 100;
            app.speedEditFieldLabel = uilabel(app.ObjectconfigrationPanel);
            app.speedEditFieldLabel.Text = 'speed';
            app.speed = uieditfield(app.ObjectconfigrationPanel, 'numeric');
            app.speed.Value = 10;
            app.DOAEditFieldLabel = uilabel(app.ObjectconfigrationPanel);
            app.DOAEditFieldLabel.Text = 'DOA';
            app.DOA = uieditfield(app.ObjectconfigrationPanel, 'text');
            app.DOA.Value = '0.1, 0.2, 0';
            app.varianceofnoiseLabel = uilabel(app.ObjectconfigrationPanel);
            app.varianceofnoiseLabel.Text = {'variance'; 'of noise'};
            app.variance = uieditfield(app.ObjectconfigrationPanel, 'numeric');
            app.variance.Value = 6;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end